classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BlueSlider        matlab.ui.control.Slider
        BlueSliderLabel   matlab.ui.control.Label
        GreenSlider       matlab.ui.control.Slider
        GreenSliderLabel  matlab.ui.control.Label
        RedSlider         matlab.ui.control.Slider
        RedSliderLabel    matlab.ui.control.Label
        QuantizeButton    matlab.ui.control.Button
        LoadImageButton   matlab.ui.control.Button
        QuantizedAxes     matlab.ui.control.UIAxes
        OriginalAxes      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        OriginalImage
        OriginalImageName
        OriginalImagePath
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function loadImage(app, event)
            % Set to main directory
            cd(fileparts(mfilename('fullpath')))
            
            % Pull image
            imageFolder = fullfile('..', 'images');
            [file, path] = uigetfile({'*.png;*.jpg;'}, "Select image", imageFolder);

            % Store image details in properties
            app.OriginalImageName = file;
            app.OriginalImagePath = fullfile(path, file);
            app.OriginalImage = imread(app.OriginalImagePath);

            % Show image on axes
            imshow(app.OriginalImage, 'Parent', app.OriginalAxes);
        end

        % Value changed function: RedSlider
        function RedSliderChanged(app, event)
            app.RedSlider.Value = round(app.RedSlider.Value);           
        end

        % Value changed function: GreenSlider
        function GreenSliderChanged(app, event)
            app.GreenSlider.Value = round(app.GreenSlider.Value);
        end

        % Value changed function: BlueSlider
        function BlueSliderChanged(app, event)
            app.BlueSlider.Value = round(app.BlueSlider.Value);            
        end

        % Button pushed function: QuantizeButton
        function quantizeImage(app, event)
            % Do nothing if no image was provided
            if (app.OriginalImagePath == "")
                return;
            end

            % Call uniform quantization function
            bitVector = [app.RedSlider.Value, app.GreenSlider.Value, app.BlueSlider.Value];
            qimg = uniformQuant(app.OriginalImagePath, bitVector);
            imshow(qimg, 'Parent', app.QuantizedAxes)

            % Save image
            outputFolder = fullfile('..', 'output');
            imwrite(qimg, fullfile(outputFolder, ['quantized_' app.OriginalImageName]))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalAxes = uiaxes(app.UIFigure);
            title(app.OriginalAxes, 'Original')
            app.QuantizedAxes = uiaxes(app.UIFigure);
            title(app.QuantizedAxes, 'Quantized')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadImageButton.Text = 'Load Image';
            app.QuantizeButton = uibutton(app.UIFigure, 'push');
            app.QuantizeButton.ButtonPushedFcn = createCallbackFcn(app, @quantizeImage, true);
            app.QuantizeButton.Text = 'Quantize';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 8];
            app.RedSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderChanged, true);
            app.RedSlider.MinorTicks = [0 1 2 3 4 5 6 7 8];
            app.RedSlider.Value = 8;
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 8];
            app.GreenSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderChanged, true);
            app.GreenSlider.MinorTicks = [0 1 2 3 4 5 6 7 8];
            app.GreenSlider.Value = 8;
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 8];
            app.BlueSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderChanged, true);
            app.BlueSlider.MinorTicks = [0 1 2 3 4 5 6 7 8];
            app.BlueSlider.Value = 8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end